classdef Etch_a_sketch_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadImageButton         matlab.ui.control.Button
        DrawButton              matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        yPixelHeightLabel       matlab.ui.control.Label
        yPixelHeightSlider      matlab.ui.control.Slider
        xPixelWidthSliderLabel  matlab.ui.control.Label
        xPixelWidthSlider       matlab.ui.control.Slider
        ClearRectangleButton    matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function moveitto(app,gox,goy)
            
            %bring global variables into function
            global time;
            global a;
            global currentx currenty;
            global currentdirx currentdiry;
            global backlashx backlashy;
            global stepX dirX stepY dirY enPin;
            
            %determine screen distance to move (does not factor in backlash) in x and y directions
            disx=gox-currentx;
            disy=goy-currenty;
            
            %mark direction of new movement
            %this is needed for backlash consideration
            if disx<=0
                directionx=0;
            else
                directionx=1;
            end
            
            if disy<=0
                directiony=0;
            else
                directiony=1;
            end
            
            %%%%%%%%%%%system to add or subtract backlash%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %did direction change in the x? and was there was movement in the x?  if yes backlash needs
            %is added or subtracted in
            if directionx~=currentdirx && disx~=0
                
                if directionx==0 %directionx change and is now negative
                    disx=disx-backlashx; %backlash must be subtracted
                end
                
                if directionx==1 %directionx change and is now positive
                    disx=disx+backlashx; %backlash must be added
                end
                
                currentdirx=directionx; %for next time function is called currentdirx is set
                
            end
            
            %did direction change in the y? and was there was movement in the y?  if yes backlash needs
            %is added or subtracted in
            if directiony~=currentdiry && disy~=0
                if directiony==0 %directiony change and is now negative
                    disy=disy-backlashy; %backlash must be subtracted
                end
                
                if directiony==1 %directiony change and is now positive
                    disy=disy+backlashy; %backlash must be added
                end
                
                currentdiry=directiony; %for next time function is called currentdiry is set
                
            end
            
            
            %position input to step commands are positive when read into arduino
            disx=abs(disx);
            disy=abs(disy);
            
            
            if disx>disy   %deterime which one is large movement
                ratio=disx/disy;  %deterime ratio greater than or equal to 1
                largesize=disx;  %major movement set
                bigdir=dirY;     %pins determined to control major and minor movement
                smalldir=dirX;
                bigstep=stepY;
                smallstep=stepX;
                bigdirectionvalue=directionx;  %direction set
                smalldirectionvalue=directiony;
            else
                ratio=disy/disx; %deterime ratio greater than or equal to 1
                largesize=disy;  %major movement set
                bigdir=dirX;   %pins determined to control major and minor movement
                smalldir=dirY;
                bigstep=stepX;
                smallstep=stepY;
                bigdirectionvalue=directiony;   %direction set
                smalldirectionvalue=directionx;
            end
            
            count=1 ;
            
            if disx+disy>0   %if both are 0 then no movement occurs, This can accidently happen when applying rounding.
                
                writeDigitalPin(a,bigdir,bigdirectionvalue); %writes direction
                writeDigitalPin(a,smalldir,smalldirectionvalue); %writes direction
                
                
                for i=1:largesize
                    
                    writeDigitalPin(a,bigstep,1); %each loop writes one major steps;
                    
                    if i==round(ratio*count) && ratio~=1/0   %if it is due, a minor step is written,  ratio~=1/0 means minor step is never written if minor step=0
                        writeDigitalPin(a,smallstep,1);
                    end
                    
                    %pause(time/1000);
                    writeDigitalPin(a,bigstep,0);
                    
                    if i==round(ratio*count)&& ratio~=1/0   %if it is due a minor step is written
                        writeDigitalPin(a,smallstep,0);
                        count=count+1; %sets number of counts from small movement, used to determine if minor step is due
                    end
                    
                    
                end
            end
            currentx=gox;
            currenty=goy;
            
        end
        
        
        function matff = initialize_pic(app)
            global pixelx pixely picture
            
            picturegray = rgb2gray(picture);  % Each pixel has one value for greyscale
            [sizey,sizex]=size(picturegray);
            if sizey > 600 || sizex >800
                yscale = 600/sizey;
                xscale = 800/sizex;
                picturegray = imresize(picturegray,min(xscale,yscale));
            end
            
            [sizey,sizex]=size(picturegray);
            % check if xdim is a factor of pixelx, if not add white space
            xdiff = pixelx-mod(sizex,pixelx);
            if xdiff>0&&xdiff<pixelx
                picturegray = [picturegray,ones(sizey,xdiff)*255];
                [sizey,sizex]=size(picturegray);
            end
            % check if ydim is a factor of pixely, if not add white space
            ydiff = pixely-mod(sizey,pixely);
            if ydiff>0&&ydiff<pixely
                picturegray = [ones(ydiff,sizex)*255;picturegray];
                [sizey,sizex]=size(picturegray);
            end
            
            quantizepixels=zeros((sizey/pixely),(sizex/pixelx));  %Makes matrix of 0s that are filled in in the loop below
            numberofpointsineach=pixelx*pixely;   %Number of points that are averaged to create new pixel
            for i=1:sizey
                for ii=1:sizex
                    quatnizeposx=ceil(ii/pixelx);  %determines fn_xyation in quantizepixelmatrix
                    quatnizeposy=ceil(i/pixely);   %determines fn_xyation in quantizepixelmatriy
                    
                    quantizepixels(quatnizeposy,quatnizeposx)=quantizepixels(quatnizeposy,quatnizeposx)+(picturegray (i,ii)/numberofpointsineach);  %averages pixels and puts in large pixel
                end
            end
            
            quantizepixels=uint8(quantizepixels);
            matff=double(quantizepixels);
            matff = flip(matff,1);
        end
        
        function [X,Y] = mona_lisa(app,box)
            global pixelx pixely shade_mat
            
            X = [];
            Y = [];
            len = box(2,2);
            w = box(2,1);
            ymin = box(1,2);
            for x_dir = box(1,1):w
                for y_dir = box(1,2):len
                    if(rem(x_dir,2) == 1 && shade_mat(y_dir, x_dir) >0)
                        X = [X ,(x_dir - 1)* pixelx, shade_mat(y_dir,x_dir) + (x_dir - 1)* pixelx , (x_dir - 1)* pixelx];
                        Y = [Y,y_dir*pixely, y_dir*pixely, y_dir*pixely];
                    elseif(rem(x_dir,2) == 0 && shade_mat((len - y_dir +ymin), x_dir) > 0)
                        X = [X ,(x_dir - 1)* pixelx, shade_mat((len - y_dir +ymin), x_dir) + (x_dir - 1)* pixelx , (x_dir - 1)* pixelx];
                        Y = [Y,(len - y_dir +ymin)*pixely, (len - y_dir +ymin)*pixely, (len - y_dir +ymin)*pixely];
                        if Y(end)<box(1,2)
                            Y(end)
                        end
                    end
                end
            end
        end
        
        
        function Preview(app)
            global pixely pixelx fn_xy Xfin Yfin picture_resize shade_mat shade_amt
            ymatsize = 600/pixely;
            xmatsize = 800/pixelx;
            matff = double(imresize(picture_resize, [ymatsize xmatsize]));
            matff = flip(matff,1);
            shade_mat = round((255 - matff).*shade_amt./255);
            
            Xfin =[];
            Yfin =[];
            
            if size(fn_xy,1)==2
                rectangle('Position',[fn_xy(1,:),fn_xy(2,:)-fn_xy(1,:)],'Parent',app.UIAxes)
                fn_xy = ceil([fn_xy(:,1)./pixelx,fn_xy(:,2)./pixely]);
                if fn_xy(1,1)==0
                    fn_xy(1,1)=1;
                end
                fnmin = fn_xy(1,:);
                fnmax = fn_xy(2,:);
                
                if mod(fnmax(1)-fnmin(1),2)==0
                    fnmax(1) = fnmax(1)-1;
                end
                % split image into sections
                %first box
                box1 = [1,  1;    fnmin(1)-1, ymatsize];
                box2 = [fnmin(1),  fnmax(2)+1;   fnmax(1), ymatsize];
                box3 = [fnmin(1),  fnmin(2);   fnmax(1), fnmax(2)];
                box4 = [fnmin(1), 1; fnmax(1), fnmin(2)-1];
                box5 = [fnmax(1)+1, 1; xmatsize,ymatsize];
                ini = box3(2,:);
                fin = box3(1,:);
                % if going up in box 4 first switch box 2 and 4
                if mod(fnmin(1),2)==1
                    tempbox = box2;
                    box2 = box4;
                    box4 = tempbox;
                    ini = box3(1,:);
                    fin = box3(2,:);
                end
                
                % check box1
                if box1(2,1)-box1(1,1)>-1 && box1(2,2)-box1(1,2)>-1
                    [X,Y] = mona_lisa(app,box1);
                    Xfin = [Xfin,X];
                    Yfin = [Yfin,Y];
                    
                end
                % check box2
                if box2(2,1)-box2(1,1)>-1 && box2(2,2)-box2(1,2)>-1
                    [X,Y] = mona_lisa(app,box2);
                    Xfin = [Xfin,X];
                    Yfin = [Yfin,Y];
                end
                % check box3 find nearest
                if box3(2,1)-box3(1,1)>-1 && box3(2,2)-box3(1,2)>-1
                    [X,Y] = pointHunter(app,ini,fin,box3);
                    Xfin = [Xfin,X];
                    Yfin = [Yfin,Y];
                end
                % check box4
                if box4(2,1)-box4(1,1)>-1 && box4(2,2)-box4(1,2)>-1
                    [X,Y] = mona_lisa(app,box4);
                    Xfin = [Xfin,X];
                    Yfin = [Yfin,Y];
                end
                % check box5
                if box5(2,1)-box5(1,1)>-1 && box5(2,2)-box5(1,2)>-1
                    [X,Y] = mona_lisa(app,box5);
                    Xfin = [Xfin,X];
                    Yfin = [Yfin,Y];
                end
            else
                cla(app.UIAxes)
                [X,Y] = mona_lisa(app,[1,1;xmatsize,ymatsize]);
                Xfin = [Xfin,X];
                Yfin = [Yfin,Y];
                
            end
            plot(app.UIAxes,Xfin,Yfin,'k')
            xlim(app.UIAxes,[0 800])
            ylim(app.UIAxes,[0 600])
        end
        
        function [X,Y] = pointHunter(app,ini,fin,box3)
            global shade_mat pixelx pixely
            
            [row_val, col_val] = find(shade_mat > 0);
            row_val_act = row_val(find(row_val >= box3(1,2) & row_val <= box3(2,2)&col_val >= box3(1,1) & col_val <= box3(2,1)));
            col_val_act = col_val(find(row_val >= box3(1,2) & row_val <= box3(2,2)&col_val >= box3(1,1) & col_val <= box3(2,1)));
            
            %Intial point to start nearest point hunter
            X_Y_Cord = ini;
            
            % The final point finder
            dist = sqrt((row_val_act- fin(2)).^2 + (col_val_act- fin(1)).^2);
            [min_in, ~] = find(dist == min(dist), 1);
            finalpoint = [col_val_act(min_in), row_val_act(min_in)];
            
            %Loop to find the closest point to the previous point
            for i = 1:length(row_val_act)
                dist = sqrt((row_val_act- X_Y_Cord(i,2)).^2 + (col_val_act- X_Y_Cord(i,1)).^2);
                [min_ind, ~] = find(dist == min(dist), 1);
                X_Y_Cord = [X_Y_Cord; col_val_act(min_ind), row_val_act(min_ind)];
                row_val_act(min_ind) = [];    col_val_act(min_ind) = [];
            end
            X_Y_Cord = [X_Y_Cord;finalpoint];
            X = [];
            Y = [];
            for m = 1:size(X_Y_Cord,1)
                x_dir = X_Y_Cord(m,1);
                y_dir = X_Y_Cord(m,2);
                X = [X ,(x_dir - 1)* pixelx, shade_mat(y_dir,x_dir) + (x_dir - 1)* pixelx , (x_dir - 1)* pixelx];
                Y = [Y,y_dir*pixely, y_dir*pixely, y_dir*pixely];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text = '';
            clear all;
            
            global pixelx pixely shade_amt
            pixelx = 5;
            pixely = 2;
            shade_amt = 8;
            
            global fn_xy
            fn_xy = [];
            
            global a; % create an arduino object
            
            a = arduino('COM7', 'Uno');  %this is an internal program in matlab that creates a follower program in the Arduino that is controlled by the leader matlab program.   The first time this runs it will take a little longer as it will downlad the follower program into the arduino.
            
            %Set and configure pins for arduino
            global stepX dirX stepY dirY enPin;
            
            stepX = 'D2';
            dirX  = 'D5';
            stepY = 'D3';
            dirY  = 'D6';
            enPin = 'D8';
            
            configurePin(a,'D2','DigitalOutput')
            configurePin(a,'D5','DigitalOutput')
            configurePin(a,'D3','DigitalOutput')
            configurePin(a,'D6','DigitalOutput')
            configurePin(a,'D8','DigitalOutput')
            
            % Set initial position, lower left hand corner
            %note full range is approximately rangex=875 full rangey=610
            %repeatively going out of range causes the cables to slip in the
            %etch-a-sketch and will wear it out more quickly
            global currentx currenty;
            currentx=0;
            currenty=0;
            
            %establishes where backlash starts: 0 is negative, 1 is positive
            %note initial value only influences minor position of x,y start axis
            global currentdirx currentdiry;
            currentdirx=1;
            currentdiry=1;
            
            %set initial backlash; number of steps after changeing direciton in order for
            %visible movement to occur. This is a positive value
            global backlashx backlashy;
            backlashx=8;
            backlashy=8;
            
            global time;
            time=0; %This value of time pauses bewteen movements. note because of arduino and matlab communication this can only increase the speed to a certain degree. 0 is full speed.
            
            
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global picture_resize
            [file,path] = uigetfile( ...
                {'*.jpg;*.jpeg;*.png','Image Files (*.jpg,*.jpeg,*.png)';
                '*.*',  'All Files (*.*)'}, ...
                'Select a File');
            app.Label.Text = string(file)+' selected.';
            picture=imread(fullfile(path, file));  % each pixel has a value for red, blue, and green
            picturegray = rgb2gray(picture);  % Each pixel has one value for greyscale
            picture_resize = imresize(picturegray,[600 800]);% resize to fit in frame if too large
            Preview(app);
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            global Xfin Yfin
            for i=1:length(Xfin)
                moveitto(app,Xfin(i),Yfin(i));
            end
            
        end

        % Value changed function: yPixelHeightSlider
        function yPixelHeightSliderValueChanged(app, event)
            global pixely
            yval = round(app.yPixelHeightSlider.Value);
            pixely = yval*2;
            app.yPixelHeightSlider.Value = yval;
            Preview(app);
        end

        % Value changed function: xPixelWidthSlider
        function xPixelWidthSliderValueChanged(app, event)
            global pixelx
            xval = round(app.xPixelWidthSlider.Value);
            if xval==1
                pixelx = 5;
            elseif xval==2
                pixelx = 8;
            else
                pixelx = 10;
            end
            app.xPixelWidthSlider.Value = xval;
            Preview(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
%             global fn_xy
%             temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
%             if size(fn_xy,1)<2
%                 fn_xy = [fn_xy;temp(1,1) temp(1,2)] % Gets the (x,y) coordinates
%             end
%             if size(fn_xy,1)==2
%                 fn_xy = sort(fn_xy,1);
%                 Preview(app);
%             end
            
        end

        % Button pushed function: ClearRectangleButton
        function ClearRectangleButtonPushed(app, event)
            global fn_xy
            fn_xy = [];
%             cla(app.UIAxes)
            Preview(app)
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            global fn_xy
            temp = app.UIAxes_2.CurrentPoint; % Returns 2x3 array of points
            if size(fn_xy,1)<2
                fn_xy = [fn_xy;temp(1,1) temp(1,2)] % Gets the (x,y) coordinates
            end
            if size(fn_xy,1)==2
                fn_xy = sort(fn_xy,1);
                Preview(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.yPixelHeightLabel = uilabel(app.UIFigure);
            app.yPixelHeightLabel.Text = 'y Pixel Height';
            app.yPixelHeightSlider = uislider(app.UIFigure);
            app.yPixelHeightSlider.Limits = [1 3];
            app.yPixelHeightSlider.MajorTicks = [1 2 3];
            app.yPixelHeightSlider.MajorTickLabels = {'small', 'medium', 'large'};
            app.yPixelHeightSlider.ValueChangedFcn = createCallbackFcn(app, @yPixelHeightSliderValueChanged, true);
            app.yPixelHeightSlider.MinorTicks = [1 2 3];
            app.yPixelHeightSlider.Value = 1;
            app.xPixelWidthSliderLabel = uilabel(app.UIFigure);
            app.xPixelWidthSliderLabel.Text = 'x Pixel Width';
            app.xPixelWidthSlider = uislider(app.UIFigure);
            app.xPixelWidthSlider.Limits = [1 3];
            app.xPixelWidthSlider.MajorTicks = [1 2 3];
            app.xPixelWidthSlider.MajorTickLabels = {'small', 'medium', 'large'};
            app.xPixelWidthSlider.ValueChangedFcn = createCallbackFcn(app, @xPixelWidthSliderValueChanged, true);
            app.xPixelWidthSlider.MinorTicks = [1 2 3];
            app.xPixelWidthSlider.Value = 1;
            app.ClearRectangleButton = uibutton(app.UIFigure, 'push');
            app.ClearRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRectangleButtonPushed, true);
            app.ClearRectangleButton.Text = 'Clear Rectangle';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 800];
            app.UIAxes.YLim = [0 600];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XLim = [0 800];
            app.UIAxes_2.YLim = [0 600];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Etch_a_sketch_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end